classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartSystemButton         matlab.ui.control.Button
        YawLabel                  matlab.ui.control.Label
        PLabel                    matlab.ui.control.Label
        RLabel                    matlab.ui.control.Label
        ZLabel                    matlab.ui.control.Label
        YLabel                    matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        EndEffectorPositionLabel  matlab.ui.control.Label
        plusZ                     matlab.ui.control.Button
        minZ                      matlab.ui.control.Button
        plusY                     matlab.ui.control.Button
        minY                      matlab.ui.control.Button
        minX                      matlab.ui.control.Button
        plusX                     matlab.ui.control.Button
        JogmmEditField            matlab.ui.control.EditField
        JogmmEditFieldLabel       matlab.ui.control.Label
        TeachLabel                matlab.ui.control.Label
        JointAngle7Slider         matlab.ui.control.Slider
        JointAngle7SliderLabel    matlab.ui.control.Label
        JointAngle6Slider         matlab.ui.control.Slider
        JointAngle6SliderLabel    matlab.ui.control.Label
        JointAngle5Slider         matlab.ui.control.Slider
        JointAngle5SliderLabel    matlab.ui.control.Label
        JointAngle4Slider         matlab.ui.control.Slider
        JointAngle4SliderLabel    matlab.ui.control.Label
        JointAngle3Slider         matlab.ui.control.Slider
        JointAngle3SliderLabel    matlab.ui.control.Label
        JointAngle2Slider         matlab.ui.control.Slider
        JointAngle2SliderLabel    matlab.ui.control.Label
        JointAngle1Slider         matlab.ui.control.Slider
        JointAngle1SliderLabel    matlab.ui.control.Label
        Switch_2                  matlab.ui.control.Switch
        Image2                    matlab.ui.control.Image
        RunningLamp               matlab.ui.control.Lamp
        RunningLampLabel          matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        ESTOPButton               matlab.ui.control.Button
        RobotJoggingLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        ur5;
        yaskawa;
        assignment2;
        currentRobot = 'UR5';
        endEff;
        jogVal;
        systemRunning = false; 
        estopPressed = false;
        arduinoObj;
        estopPin = 'D2';
        startupPin = 'D3';
        usingHardware = false;
        lastEstopPressTime;
        lastStartPressTime;
        debounceDelay = 0.5; % seconds
        
    end
    methods (Access = public)
        
        function updateEndEffectorPositionLabel(app)
            if strcmp(app.currentRobot, 'UR5')
                
                qmatrix = app.ur5.model.getpos();
                app.JointAngle1Slider.Value = qmatrix(1);
                app.JointAngle2Slider.Value = qmatrix(2);
                app.JointAngle3Slider.Value = qmatrix(3);
                app.JointAngle4Slider.Value = qmatrix(4);
                app.JointAngle5Slider.Value = qmatrix(5);
                app.JointAngle6Slider.Value = qmatrix(6);
                app.JointAngle7Slider.Value = qmatrix(7);
                endefftr = app.ur5.model.fkine(qmatrix).T;
                endeff = endefftr(1:3,4)';
                rpy = rad2deg(tr2rpy(endefftr(1:3,1:3)));
                % Update the text of the EndEffectorPositionLabel
                app.XLabel.Text = sprintf('X = %.2f', endeff(1));
                app.YLabel.Text = sprintf('Y = %.2f', endeff(2));
                app.ZLabel.Text = sprintf('Z = %.2f', endeff(3));
                app.RLabel.Text = sprintf('R = %.2f', rpy(1));
                app.PLabel.Text = sprintf('P = %.2f', rpy(2));
                app.YawLabel.Text = sprintf('Y = %.2f', rpy(3));
           elseif strcmp(app.currentRobot, 'Yaskawa')
                qmatrix = app.yaskawa.model.getpos();
                app.JointAngle1Slider.Value = qmatrix(1);
                app.JointAngle2Slider.Value = qmatrix(2);
                app.JointAngle3Slider.Value = qmatrix(3);
                app.JointAngle4Slider.Value = qmatrix(4);
                app.JointAngle5Slider.Value = qmatrix(5);
                app.JointAngle6Slider.Value = qmatrix(6);
                endefftr = app.yaskawa.model.fkine(qmatrix).T;
                endeff = endefftr(1:3,4)';
                rpy = rad2deg(tr2rpy(endefftr(1:3,1:3)));
                % Update the text of the EndEffectorPositionLabel
                app.XLabel.Text = sprintf('X = %.2f', endeff(1));
                app.YLabel.Text = sprintf('Y = %.2f', endeff(2));
                app.ZLabel.Text = sprintf('Z = %.2f', endeff(3));
                app.RLabel.Text = sprintf('R = %.2f', rpy(1));
                app.PLabel.Text = sprintf('P = %.2f', rpy(2));
                app.YawLabel.Text = sprintf('Y = %.2f', rpy(3));
            end

        end
        
        function readButtonState(app)
            estopState = readDigitalPin(app.arduinoObj, app.estopPin);
            startupState = readDigitalPin(app.arduinoObj, app.startupPin);
            current_time = datetime('now');
            
            % eStop button logic
            if estopState == 0
                if isempty(app.lastEstopPressTime)
                    app.lastEstopPressTime = current_time;
                elseif (current_time - app.lastEstopPressTime) * 24 * 3600 > app.debounceDelay
                    ESTOPButtonPushed(app,1);
                    app.lastEstopPressTime = []; % Clear the timestamp
                end
            else
                app.lastEstopPressTime = []; % Clear the timestamp if button is not pressed
            end
             % Start button logic
            if startupState == 0 && ~app.estopPressed
                if isempty(app.lastStartPressTime)
                    app.lastStartPressTime = current_time;
                elseif (current_time - app.lastStartPressTime) * 24 * 3600 > app.debounceDelay
                    StartSystemButtonPushed(app,1);
                    app.lastStartPressTime = []; % Clear the timestamp
                end
            else
                app.lastStartPressTime = []; % Clear the timestamp if button is not pressed
            end
            
        end
        
        function lightCurtainActive(app)
            ESTOPButtonPushed(app,1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ur5, yaskawa)
            clear app.arduinoObj;
            if app.usingHardware
                app.arduinoObj = arduino();
                configurePin(app.arduinoObj, app.estopPin, 'Pullup');
                configurePin(app.arduinoObj, app.startupPin, 'Pullup');
            end
            app.ur5 = ur5;
            app.yaskawa = yaskawa;
            app.JointAngle1Slider.Limits = app.ur5.model.qlim(1,:) + [0.00 0.005];
            app.JointAngle2Slider.Limits = app.ur5.model.qlim(2,:);
            app.JointAngle3Slider.Limits = app.ur5.model.qlim(3,:);
            app.JointAngle4Slider.Limits = app.ur5.model.qlim(4,:);
            app.JointAngle5Slider.Limits = app.ur5.model.qlim(5,:) + [-0.01 0.01];
            app.JointAngle6Slider.Limits = app.ur5.model.qlim(6,:);
            app.JointAngle7Slider.Limits = app.ur5.model.qlim(7,:);
            qmatrix = app.ur5.model.getpos();
            app.JointAngle1Slider.Value = qmatrix(1);
            app.JointAngle2Slider.Value = qmatrix(2);
            app.JointAngle3Slider.Value = qmatrix(3);
            app.JointAngle4Slider.Value = qmatrix(4);
            app.JointAngle5Slider.Value = qmatrix(5);
            app.JointAngle6Slider.Value = qmatrix(6);
            app.JointAngle7Slider.Value = qmatrix(7);

        end

        % Value changed function: JointAngle1Slider
        function JointAngle1SliderValueChanged(app, event)
            if strcmp(app.currentRobot, 'UR5')
                newJointAngles = app.ur5.model.getpos();
                newJointAngles(1) = app.JointAngle1Slider.Value;
                app.assignment2.updateUR5(newJointAngles);
            elseif strcmp(app.currentRobot, 'Yaskawa')
                newJointAngles = app.yaskawa.model.getpos();
                newJointAngles(1) = app.JointAngle1Slider.Value;
                app.assignment2.updateYaskawa(newJointAngles);
            end
        end

        % Value changed function: JointAngle2Slider
        function JointAngle2SliderValueChanged(app, event)
            if strcmp(app.currentRobot, 'UR5')
                newJointAngles = app.ur5.model.getpos();
                newJointAngles(2) = app.JointAngle2Slider.Value;
                app.assignment2.updateUR5(newJointAngles);
            elseif strcmp(app.currentRobot, 'Yaskawa')
                newJointAngles = app.yaskawa.model.getpos();
                newJointAngles(2) = app.JointAngle2Slider.Value;
                app.assignment2.updateYaskawa(newJointAngles);
            end
        end

        % Value changed function: JointAngle3Slider
        function JointAngle3SliderValueChanged(app, event)
            if strcmp(app.currentRobot, 'UR5')
                newJointAngles = app.ur5.model.getpos();
                newJointAngles(3) = app.JointAngle3Slider.Value;
                app.assignment2.updateUR5(newJointAngles);
            elseif strcmp(app.currentRobot, 'Yaskawa')
                newJointAngles = app.yaskawa.model.getpos();
                newJointAngles(3) = app.JointAngle3Slider.Value;
                app.assignment2.updateYaskawa(newJointAngles);
            end
        end

        % Value changed function: JointAngle4Slider
        function JointAngle4SliderValueChanged(app, event)
            if strcmp(app.currentRobot, 'UR5')
                newJointAngles = app.ur5.model.getpos();
                newJointAngles(4) = app.JointAngle4Slider.Value;
                app.assignment2.updateUR5(newJointAngles);
            elseif strcmp(app.currentRobot, 'Yaskawa')
                newJointAngles = app.yaskawa.model.getpos();
                newJointAngles(4) = app.JointAngle4Slider.Value;
                app.assignment2.updateYaskawa(newJointAngles);
            end
        end

        % Value changed function: JointAngle5Slider
        function JointAngle5SliderValueChanged(app, event)
            if strcmp(app.currentRobot, 'UR5')
                newJointAngles = app.ur5.model.getpos();
                newJointAngles(5) = app.JointAngle5Slider.Value;
                app.assignment2.updateUR5(newJointAngles);
            elseif strcmp(app.currentRobot, 'Yaskawa')
                newJointAngles = app.yaskawa.model.getpos();
                newJointAngles(5) = app.JointAngle5Slider.Value;
                app.assignment2.updateYaskawa(newJointAngles);
            end
        end

        % Value changed function: JointAngle6Slider
        function JointAngle6SliderValueChanged(app, event)
            if strcmp(app.currentRobot, 'UR5')
                newJointAngles = app.ur5.model.getpos();
                newJointAngles(6) = app.JointAngle6Slider.Value;
                app.assignment2.updateUR5(newJointAngles);
            elseif strcmp(app.currentRobot, 'Yaskawa')
                newJointAngles = app.yaskawa.model.getpos();
                newJointAngles(6) = app.JointAngle6Slider.Value;
                app.assignment2.updateYaskawa(newJointAngles);
            end
        end

        % Value changed function: JointAngle7Slider
        function JointAngle7SliderValueChanged(app, event)
            newJointAngles = app.ur5.model.getpos();
            newJointAngles(7) = app.JointAngle7Slider.Value;
            app.assignment2.updateUR5(newJointAngles);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
        % Determine which robot to control based on the switch state
            if app.Switch_2.Value == "Yaskawa"
                app.currentRobot = 'Yaskawa';    
                app.JointAngle1Slider.Limits = app.yaskawa.model.qlim(1,:) + [-0.001 0.001];
                app.JointAngle2Slider.Limits = app.yaskawa.model.qlim(2,:);
                app.JointAngle3Slider.Limits = app.yaskawa.model.qlim(3,:);
                app.JointAngle4Slider.Limits = app.yaskawa.model.qlim(4,:);
                app.JointAngle5Slider.Limits = app.yaskawa.model.qlim(5,:);
                app.JointAngle6Slider.Limits = app.yaskawa.model.qlim(6,:);
                qmatrix = app.yaskawa.model.getpos()
                app.JointAngle1Slider.Value = qmatrix(1);
                app.JointAngle2Slider.Value = qmatrix(2);
                app.JointAngle3Slider.Value = qmatrix(3);
                app.JointAngle4Slider.Value = qmatrix(4);
                app.JointAngle5Slider.Value = qmatrix(5);
                app.JointAngle6Slider.Value = qmatrix(6);
                app.updateEndEffectorPositionLabel();
            elseif app.Switch_2.Value == "Linear UR5"
                app.currentRobot = 'UR5';
                app.JointAngle1Slider.Limits = app.ur5.model.qlim(1,:);
                app.JointAngle2Slider.Limits = app.ur5.model.qlim(2,:);
                app.JointAngle3Slider.Limits = app.ur5.model.qlim(3,:);
                app.JointAngle4Slider.Limits = app.ur5.model.qlim(4,:);
                app.JointAngle5Slider.Limits = app.ur5.model.qlim(5,:);
                app.JointAngle6Slider.Limits = app.ur5.model.qlim(6,:);
                app.JointAngle7Slider.Limits = app.ur5.model.qlim(7,:);
                qmatrix = app.ur5.model.getpos();
                app.JointAngle1Slider.Value = qmatrix(1);
                app.JointAngle2Slider.Value = qmatrix(2);
                app.JointAngle3Slider.Value = qmatrix(3);
                app.JointAngle4Slider.Value = qmatrix(4);
                app.JointAngle5Slider.Value = qmatrix(5);
                app.JointAngle6Slider.Value = qmatrix(6);
                app.JointAngle7Slider.Value = qmatrix(7);
                app.updateEndEffectorPositionLabel();
            end
            
        end

        % Value changed function: JogmmEditField
        function JogmmEditFieldValueChanged(app, event)
            app.jogVal = app.JogmmEditField.Value;
            app.jogVal = str2double(app.jogVal); % Convert val from string to a number
        end

        % Button pushed function: plusX
        function plusXButtonPushed(app, event)
            
            if strcmp(app.currentRobot, 'UR5')
                app.assignment2.robotJogging(app.jogVal, 1, 1);
            else
                app.assignment2.robotJogging(app.jogVal, 1, 2);
            end
        end

        % Button pushed function: minX
        function minXButtonPushed(app, event)
            if strcmp(app.currentRobot, 'UR5')
                app.assignment2.robotJogging(-app.jogVal, 1, 1);
            else
                app.assignment2.robotJogging(-app.jogVal, 1, 2); 
            end
        end

        % Button pushed function: plusY
        function plusYButtonPushed(app, event)
            if strcmp(app.currentRobot, 'UR5')
                app.assignment2.robotJogging(app.jogVal, 2, 1);
            else
                app.assignment2.robotJogging(app.jogVal, 2, 2);
            end
        end

        % Button pushed function: minY
        function minYButtonPushed(app, event)
            if strcmp(app.currentRobot, 'UR5')
                app.assignment2.robotJogging(-app.jogVal, 2, 1);
            else
                app.assignment2.robotJogging(-app.jogVal, 2, 2); 
            end
        end

        % Button pushed function: plusZ
        function plusZButtonPushed(app, event)
            if strcmp(app.currentRobot, 'UR5')
                app.assignment2.robotJogging(app.jogVal, 3, 1);
            else
                app.assignment2.robotJogging(app.jogVal, 3, 2); 
            end
        end

        % Button pushed function: minZ
        function minZButtonPushed(app, event)
            if strcmp(app.currentRobot, 'UR5')
                app.assignment2.robotJogging(-app.jogVal, 3, 1);
            else
                app.assignment2.robotJogging(-app.jogVal, 3, 2); 
            end
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.systemRunning = false;
            app.RunningLamp.Color = [1.00 0 0];
            app.estopPressed = ~app.estopPressed;
            if app.estopPressed
                app.ESTOPButton.Text = 'Release E-stop'; % Update button text
                app.ESTOPButton.BackgroundColor = [1.00 0.41 0.16]; 
                app.StartSystemButton.Enable = 'off';
                disp('E-stop pressed. Press again to Release');
            else
                app.ESTOPButton.Text = 'E-stop';
                app.ESTOPButton.BackgroundColor = [1.00 0 0];
                disp('Emergency Button Pressed. Please Release');
                app.StartSystemButton.Enable = 'on';
                app.StartSystemButton.BackgroundColor = [1.00 0.41 0.16]; 
                app.StartSystemButton.Text = 'Resume...';
            end
            % Add a call to your Assignment2 class method to handle the pause/resume logic.
            app.assignment2.handleSystemState();
        end

        % Button pushed function: StartSystemButton
        function StartSystemButtonPushed(app, event)
            app.systemRunning = true;
            app.StartSystemButton.Enable = 'off';
            app.RunningLamp.Color = [0 1.00 0];
            app.assignment2.handleSystemState();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotJoggingLabel = uilabel(app.UIFigure);
            app.RobotJoggingLabel.Text = 'Robot Jogging';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '@Images', 'Yaskawa2.png');
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running ';
            app.RunningLamp = uilamp(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '@Images', 'Linear UR5.jpg');
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Yaskawa', 'Linear UR5'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Linear UR5';
            app.JointAngle1SliderLabel = uilabel(app.UIFigure);
            app.JointAngle1SliderLabel.Text = 'Joint Angle 1';
            app.JointAngle1Slider = uislider(app.UIFigure);
            app.JointAngle1Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle1SliderValueChanged, true);
            app.JointAngle2SliderLabel = uilabel(app.UIFigure);
            app.JointAngle2SliderLabel.Text = 'Joint Angle 2';
            app.JointAngle2Slider = uislider(app.UIFigure);
            app.JointAngle2Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle2SliderValueChanged, true);
            app.JointAngle3SliderLabel = uilabel(app.UIFigure);
            app.JointAngle3SliderLabel.Text = 'Joint Angle 3';
            app.JointAngle3Slider = uislider(app.UIFigure);
            app.JointAngle3Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle3SliderValueChanged, true);
            app.JointAngle4SliderLabel = uilabel(app.UIFigure);
            app.JointAngle4SliderLabel.Text = 'Joint Angle 4';
            app.JointAngle4Slider = uislider(app.UIFigure);
            app.JointAngle4Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle4SliderValueChanged, true);
            app.JointAngle5SliderLabel = uilabel(app.UIFigure);
            app.JointAngle5SliderLabel.Text = 'Joint Angle 5';
            app.JointAngle5Slider = uislider(app.UIFigure);
            app.JointAngle5Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle5SliderValueChanged, true);
            app.JointAngle6SliderLabel = uilabel(app.UIFigure);
            app.JointAngle6SliderLabel.Text = 'Joint Angle 6';
            app.JointAngle6Slider = uislider(app.UIFigure);
            app.JointAngle6Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle6SliderValueChanged, true);
            app.JointAngle7SliderLabel = uilabel(app.UIFigure);
            app.JointAngle7SliderLabel.Text = 'Joint Angle 7';
            app.JointAngle7Slider = uislider(app.UIFigure);
            app.JointAngle7Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle7SliderValueChanged, true);
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.JogmmEditFieldLabel = uilabel(app.UIFigure);
            app.JogmmEditFieldLabel.Text = 'Jog (mm)';
            app.JogmmEditField = uieditfield(app.UIFigure, 'text');
            app.JogmmEditField.ValueChangedFcn = createCallbackFcn(app, @JogmmEditFieldValueChanged, true);
            app.plusX = uibutton(app.UIFigure, 'push');
            app.plusX.ButtonPushedFcn = createCallbackFcn(app, @plusXButtonPushed, true);
            app.plusX.Text = '+ X';
            app.minX = uibutton(app.UIFigure, 'push');
            app.minX.ButtonPushedFcn = createCallbackFcn(app, @minXButtonPushed, true);
            app.minX.Text = '- X';
            app.minY = uibutton(app.UIFigure, 'push');
            app.minY.ButtonPushedFcn = createCallbackFcn(app, @minYButtonPushed, true);
            app.minY.Text = '- Y';
            app.plusY = uibutton(app.UIFigure, 'push');
            app.plusY.ButtonPushedFcn = createCallbackFcn(app, @plusYButtonPushed, true);
            app.plusY.Text = '+ Y';
            app.minZ = uibutton(app.UIFigure, 'push');
            app.minZ.ButtonPushedFcn = createCallbackFcn(app, @minZButtonPushed, true);
            app.minZ.Text = '- Z';
            app.plusZ = uibutton(app.UIFigure, 'push');
            app.plusZ.ButtonPushedFcn = createCallbackFcn(app, @plusZButtonPushed, true);
            app.plusZ.Text = '+ Z';
            app.EndEffectorPositionLabel = uilabel(app.UIFigure);
            app.EndEffectorPositionLabel.Text = 'End Effector Position';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X = 0.1';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y = 0.5';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z = 0.8';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R = 0';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P = 180';
            app.YawLabel = uilabel(app.UIFigure);
            app.YawLabel.Text = 'Y = 0';
            app.StartSystemButton = uibutton(app.UIFigure, 'push');
            app.StartSystemButton.ButtonPushedFcn = createCallbackFcn(app, @StartSystemButtonPushed, true);
            app.StartSystemButton.Text = 'Start System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end